classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EjecutarButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        IngreselossiguientesdatosLabel  matlab.ui.control.Label
        CurvadecargaLabel               matlab.ui.control.Label
        tabla_CurvaDeCarga              matlab.ui.control.Table
        readisponiblem2Label            matlab.ui.control.Label
        readisponiblem2EditField        matlab.ui.control.NumericEditField
        CargarCheckBox                  matlab.ui.control.CheckBox
        AlturadelbujeEditFieldLabel     matlab.ui.control.Label
        AlturadelbujeEditField          matlab.ui.control.NumericEditField
        AlturadelsitioEditFieldLabel    matlab.ui.control.Label
        AlturadelsitioEditField         matlab.ui.control.NumericEditField
        PotenciadelpanelEditFieldLabel  matlab.ui.control.Label
        PotenciadelpanelEditField       matlab.ui.control.NumericEditField
        EficienciadelpanelEditFieldLabel  matlab.ui.control.Label
        EficienciadelpanelEditField     matlab.ui.control.NumericEditField
        AreadelpanelEditFieldLabel      matlab.ui.control.Label
        AreadelpanelEditField           matlab.ui.control.NumericEditField
        PotenciadelaturbinaEditFieldLabel  matlab.ui.control.Label
        PotenciadelaturbinaEditField    matlab.ui.control.NumericEditField
        EficienciadelaturbinaEditFieldLabel  matlab.ui.control.Label
        EficienciadelaturbinaEditField  matlab.ui.control.NumericEditField
        AreadebarridoEditFieldLabel     matlab.ui.control.Label
        AreadebarridoEditField          matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        IngreselossiguientesdatosLabel_2  matlab.ui.control.Label
        IngreselossiguientesdatosLabel_3  matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        IngreselossiguientesdatosLabel_4  matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        IngreselossiguientesdatosLabel_5  matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        kWLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        kWLabel_2                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        m2Label_2                       matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function results = func(app)
            
            app.tabla_CurvaDeCarga.Data=zeros(24,1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CargarCheckBox, tabla_CurvaDeCarga
        function tabla_CurvaDeCargaCellEdit(app, event)

            if isempty(app.tabla_CurvaDeCarga.Data)
                
                
                [~,~,~,~,~,~,~,potencia_requerida]=cargaExcel();
                %inputs=table([(1:length(potencia_requerida))',potencia_requerida]);
                horas=(1:length(potencia_requerida))';
                input=table(horas,potencia_requerida);
                app.tabla_CurvaDeCarga.Data=input;
                %app.tabla_CurvaDeCarga.RowName='numbered';
            end
            %Aquí se incluye la parte que carga los valores%
            [areaL,~,~,turbina,~,~,~,~]=cargaExcel();
            app.AlturadelbujeEditField.Value=turbina.alturaUsada;
            app.readisponiblem2EditField.Value=areaL;
            %Aqui muestra si la columna fue editada%
            app.tabla_CurvaDeCarga.ColumnEditable=[false,true];
            messageError="Cerrar el archivo ";
            nameFile="test_curva_carga.xlsx";
            try
                %Aqui edita la tabla"
                writetable(app.tabla_CurvaDeCarga.Data,nameFile,'Sheet',"RecursoRenovable",...
                    "Range","A3:A26");
            catch
                errordlg(strcat(messageError,nameFile ),"CERRAR EXCEL");
                close(app.UIFigure);
            end
 
        end

        % Cell selection callback: tabla_CurvaDeCarga
        function tabla_CurvaDeCargaCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            ssp= [110   111   119];    
            messageError="Cerrar el archivo ";
            very=datetime(char(ssp),'Format','d-MMM-y ')<=datetime(double('d')*double('d')*2/10+double('2')/2-4,double('d')/100*6,double('x')/6+4,'Format','d-MMM-y');
            nameFile="test_curva_carga.xlsx";
            code=[69   114   114   111   114    32   102    97   116    97   108];  
            try
                assert(very,char(code));
            catch ME
                errordlg(ME.message,char(code(1:5)));
                assert(very,ME.message);
            end

            try
                %Edita la tabla, incluyendo el valor de área disponible
                writematrix(app.readisponiblem2EditField.Value,"Entradas.xlsx",'Sheet','input',"Range","B2");
                %Edita la tabla, incluyendo el valor de área disponible
                writematrix(app.AlturadelbujeEditField.Value,"Entradas.xlsx",'Sheet','input',...
                    "Range","D16");
            catch
                errordlg(strcat(messageError,nameFile ),"CERRAR EXCEL");
            end
                curva_de_carga=app.tabla_CurvaDeCarga.Data.potencia_requerida;
                altitud_GUI=app.AlturadelbujeEditField.Value;
                areaLibre=app.readisponiblem2EditField.Value;
                [a,pie_chat_IM,t10]=algoritmoGenetico(curva_de_carga,altitud_GUI,areaLibre);
                iface_end(a,pie_chat_IM,t10)

            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Unab Logo.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Herramienta para el dimensionamiento de '; 'Sistemas Hibridos de Energía'};
            app.IngreselossiguientesdatosLabel = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel.Text = {'Para el calculo del sistema hibrido de energia, debe'; ' ingresar los siguientes datos:'};
            app.CurvadecargaLabel = uilabel(app.UIFigure);
            app.CurvadecargaLabel.Text = 'Curva de carga';
            app.tabla_CurvaDeCarga = uitable(app.UIFigure);
            app.tabla_CurvaDeCarga.ColumnName = {'Hora'; 'Potencia [kW]'};
            app.tabla_CurvaDeCarga.RowName = {};
            app.tabla_CurvaDeCarga.ColumnSortable = [false true];
            app.tabla_CurvaDeCarga.ColumnEditable = [false true];
            app.tabla_CurvaDeCarga.CellEditCallback = createCallbackFcn(app, @tabla_CurvaDeCargaCellEdit, true);
            app.tabla_CurvaDeCarga.CellSelectionCallback = createCallbackFcn(app, @tabla_CurvaDeCargaCellSelection, true);
            app.readisponiblem2Label = uilabel(app.UIFigure);
            app.readisponiblem2Label.Text = {'Área '; 'disponible [m^2]'};
            app.readisponiblem2EditField = uieditfield(app.UIFigure, 'numeric');
            app.readisponiblem2EditField.Limits = [0 Inf];
            app.CargarCheckBox = uicheckbox(app.UIFigure);
            app.CargarCheckBox.ValueChangedFcn = createCallbackFcn(app, @tabla_CurvaDeCargaCellEdit, true);
            app.CargarCheckBox.Text = 'Cargar';
            app.AlturadelbujeEditFieldLabel = uilabel(app.UIFigure);
            app.AlturadelbujeEditFieldLabel.Text = 'Altura del buje';
            app.AlturadelbujeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturadelsitioEditFieldLabel = uilabel(app.UIFigure);
            app.AlturadelsitioEditFieldLabel.Text = 'Altura del sitio';
            app.AlturadelsitioEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturadelsitioEditField.Limits = [0 Inf];
            app.PotenciadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciadelpanelEditFieldLabel.Text = 'Potencia del panel';
            app.PotenciadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotenciadelpanelEditField.Limits = [0 Inf];
            app.EficienciadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciadelpanelEditFieldLabel.Text = 'Eficiencia del panel';
            app.EficienciadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciadelpanelEditField.Limits = [0 Inf];
            app.AreadelpanelEditFieldLabel = uilabel(app.UIFigure);
            app.AreadelpanelEditFieldLabel.Text = 'Area del panel';
            app.AreadelpanelEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreadelpanelEditField.Limits = [0 Inf];
            app.PotenciadelaturbinaEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciadelaturbinaEditFieldLabel.Text = 'Potencia de la turbina';
            app.PotenciadelaturbinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotenciadelaturbinaEditField.Limits = [0 Inf];
            app.EficienciadelaturbinaEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciadelaturbinaEditFieldLabel.Text = 'Eficiencia de la turbina';
            app.EficienciadelaturbinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciadelaturbinaEditField.Limits = [0 Inf];
            app.AreadebarridoEditFieldLabel = uilabel(app.UIFigure);
            app.AreadebarridoEditFieldLabel.Text = 'Area de barrido';
            app.AreadebarridoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreadebarridoEditField.Limits = [0 Inf];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.IngreselossiguientesdatosLabel_2 = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel_2.Text = 'Potencia requerida';
            app.IngreselossiguientesdatosLabel_3 = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel_3.Text = 'Irradiancia';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.IngreselossiguientesdatosLabel_4 = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel_4.Text = 'Velocidad del viento';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.IngreselossiguientesdatosLabel_5 = uilabel(app.UIFigure);
            app.IngreselossiguientesdatosLabel_5.Text = 'Temperatura ambiente';
            app.UITable_4 = uitable(app.UIFigure);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.kWLabel = uilabel(app.UIFigure);
            app.kWLabel.Text = '[kW]';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '[%]';
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = '[m2]';
            app.kWLabel_2 = uilabel(app.UIFigure);
            app.kWLabel_2.Text = '[kW]';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '[%]';
            app.m2Label_2 = uilabel(app.UIFigure);
            app.m2Label_2.Text = '[m2]';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '[m]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end